classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        showimage               matlab.ui.control.Button
        MenuimagenListBoxLabel  matlab.ui.control.Label
        listaimagen             matlab.ui.control.ListBox
        ButtonGroup             matlab.ui.container.ButtonGroup
        Button                  matlab.ui.control.RadioButton
        Button2                 matlab.ui.control.RadioButton
        Button3                 matlab.ui.control.RadioButton
        EscalaSliderLabel       matlab.ui.control.Label
        Escala                  matlab.ui.control.Slider
        Escala2SpinnerLabel     matlab.ui.control.Label
        Escala2Spinner          matlab.ui.control.Spinner
        axes1                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: showimage
        function showimageButtonPushed(app, event)
            im=imread('imagen_nueva.jpg');
            cla(app.axes1,'reset');
            imshow(im,'Parent',app.axes1);
        end

        % Value changed function: listaimagen
        function listaimagenValueChanged(app, event)
            value = app.listaimagen.Value
                im = imread('imagen.jpg');
            if value == "Escala de grises"
                im2=rgb2gray(im);
                imwrite(im2,'imagen_nueva.jpg');
            elseif value == "Indexada"
                [im2 map]=rgb2ind(im,200);
                imwrite(im2,map,'imagen_nueva.jpg');
            elseif value == "Rojo"
                im_r=im(:,:,1);
                im2(:,:,1)=im_r;
                im2(:,:,2)=zeros(size(im_r));
                im2(:,:,3)=zeros(size(im_r));
                imwrite(im2,'imagen_nueva.jpg');
            elseif value == "Verde"
                im_g=im(:,:,2);
                im2(:,:,1)=zeros(size(im_g));
                im2(:,:,2)=im_g;
                im2(:,:,3)=zeros(size(im_g));
                imwrite(im2,'imagen_nueva.jpg');
            elseif value == "azul"
                im_b=im(:,:,3);
                im2(:,:,1)=zeros(size(im_b));
                im2(:,:,2)=zeros(size(im_b));
                im2(:,:,3)=im_b;
                imwrite(im2,'imagen_nueva.jpg');
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            selectedButton.Value
 
            im = imread('imagen.jpg');
            if selectedButton.Text == "Button"
                im2=rgb2gray(im);
                imwrite(im2,'imagen_nueva.jpg');
            elseif selectedButton.Text == "Button2"
                [im2 map]=rgb2ind(im,200);
                imwrite(im2,map,'imagen_nueva.jpg');
            elseif selectedButton.Text == "Button3"
                im_r=im(:,:,1);
                im2(:,:,1)=im_r;
                im2(:,:,2)=zeros(size(im_r));
                im2(:,:,3)=zeros(size(im_r));
                imwrite(im2,'imagen_nueva.jpg');
            end
        end

        % Value changed function: Escala
        function EscalaValueChanged(app, event)
            value = app.Escala.Value;
            im = imread('imagen_nueva.jpg');
            cla(app.axes1,'reset');
            imshow(imresize(im,value),'Parent',app.axes1);
            
        end

        % Value changed function: Escala2Spinner
        function Escala2SpinnerValueChanged(app, event)
            value = app.Escala2Spinner.Value;
            im = imread('imagen_nueva.jpg');
            cla(app.axes1,'reset');
            imshow(imresize(im,value),'Parent',app.axes1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.showimage = uibutton(app.UIFigure, 'push');
            app.showimage.ButtonPushedFcn = createCallbackFcn(app, @showimageButtonPushed, true);
            app.showimage.Text = 'Mosrar imagen';
            app.MenuimagenListBoxLabel = uilabel(app.UIFigure);
            app.MenuimagenListBoxLabel.Text = 'Menu imagen';
            app.listaimagen = uilistbox(app.UIFigure);
            app.listaimagen.Items = {'Escala de grises', 'Indexada', 'Rojo', 'Verde', 'Azul'};
            app.listaimagen.ValueChangedFcn = createCallbackFcn(app, @listaimagenValueChanged, true);
            app.listaimagen.Value = 'Escala de grises';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.EscalaSliderLabel = uilabel(app.UIFigure);
            app.EscalaSliderLabel.Text = 'Escala';
            app.Escala = uislider(app.UIFigure);
            app.Escala.Limits = [0.2 2];
            app.Escala.ValueChangedFcn = createCallbackFcn(app, @EscalaValueChanged, true);
            app.Escala.Value = 0.2;
            app.Escala2SpinnerLabel = uilabel(app.UIFigure);
            app.Escala2SpinnerLabel.Text = 'Escala 2';
            app.Escala2Spinner = uispinner(app.UIFigure);
            app.Escala2Spinner.Step = 0.1;
            app.Escala2Spinner.Limits = [0 10];
            app.Escala2Spinner.ValueChangedFcn = createCallbackFcn(app, @Escala2SpinnerValueChanged, true);
            app.axes1 = uiaxes(app.UIFigure);
            title(app.axes1, 'Title')
            xlabel(app.axes1, 'X')
            ylabel(app.axes1, 'Y')
            zlabel(app.axes1, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end