classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        table1                  matlab.ui.control.Table
        b1                      matlab.ui.control.Button
        TermometroLabel         matlab.ui.control.Label
        termometro              matlab.ui.control.NinetyDegreeGauge
        temp_num                matlab.ui.control.Label
        temperaturaSliderLabel  matlab.ui.control.Label
        temp                    matlab.ui.control.Slider
        KnobLabel               matlab.ui.control.Label
        Knob                    matlab.ui.control.DiscreteKnob
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: b1
        function b1ButtonPushed(app, event)
            nombre = {'Mateo';'Maria';'Cristina'};
            data=importdata('texto.txt');
            edad = num2cell(data(:,1));
            altura = num2cell(data(:,2));
            tabla=table(nombre,edad,altura);
            app.table1.Data=tabla;
            
        end

        % Value changed function: temp
        function tempValueChanged(app, event)
            value = app.temp.Value;
            app.temp_num.Text = num2str(value);
            app.termometro.Value = value;
        end

        % Value changing function: temp
        function tempValueChanging(app, event)
            changingValue = event.Value;
            app.temp_num.Text = num2str(changingValue);
            app.termometro.Value = changingValue;
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.table1 = uitable(app.UIFigure);
            app.table1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.table1.RowName = {};
            app.b1 = uibutton(app.UIFigure, 'push');
            app.b1.ButtonPushedFcn = createCallbackFcn(app, @b1ButtonPushed, true);
            app.b1.Text = 'tabla';
            app.TermometroLabel = uilabel(app.UIFigure);
            app.TermometroLabel.Text = 'Termometro';
            app.termometro = uigauge(app.UIFigure, 'ninetydegree');
            app.termometro.Limits = [-100 200];
            app.temp_num = uilabel(app.UIFigure);
            app.temp_num.Text = {'0'; ''};
            app.temperaturaSliderLabel = uilabel(app.UIFigure);
            app.temperaturaSliderLabel.Text = 'temperatura';
            app.temp = uislider(app.UIFigure);
            app.temp.Limits = [-100 200];
            app.temp.ValueChangedFcn = createCallbackFcn(app, @tempValueChanged, true);
            app.temp.ValueChangingFcn = createCallbackFcn(app, @tempValueChanging, true);
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.Items = {'apagado', 'bajo', 'Medo', 'alto', ''};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'apagado';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end