classdef app_diodo_laser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        SaturacinTab            matlab.ui.container.Tab
        graf_sat                matlab.ui.control.Button
        axes1                   matlab.ui.control.UIAxes
        axes2                   matlab.ui.control.UIAxes
        EspectroTab             matlab.ui.container.Tab
        graf_espec              matlab.ui.control.Button
        axes3                   matlab.ui.control.UIAxes
        axes4                   matlab.ui.control.UIAxes
        FrecuenciaTab           matlab.ui.container.Tab
        graf_freq               matlab.ui.control.Button
        Anchodebandaa10mALabel  matlab.ui.control.Label
        Band_10m                matlab.ui.control.Label
        Anchodebandaa15mALabel  matlab.ui.control.Label
        Band_15m                matlab.ui.control.Label
        PotenciaTab             matlab.ui.container.Tab
        graf_pot                matlab.ui.control.Button
        axes5                   matlab.ui.control.UIAxes
        axes6                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: graf_sat
        function graf_satButtonPushed(app, event)
            saturacion;
            plot(app.axes1,t,y);
            app.axes1.Title.String = 'Gráfica de saturación con I=10mA';
            app.axes1.XLabel.String = 'Tiempo';
            app.axes1.YLabel.String = 'Fase del campo eléctrico';
            
            plot(app.axes2,t,y2);
            app.axes2.Title.String = 'Gráfica de saturación con I=15mA';
            app.axes2.XLabel.String = 'Tiempo';
            app.axes2.YLabel.String = 'Fase del campo eléctrico';
        end

        % Button pushed function: graf_espec
        function graf_especButtonPushed(app, event)
            espectro;
            plot(app.axes3,t,y);
            app.axes3.Title.String = 'Espectro respuesta impulso con I=10mA';
            app.axes3.XLabel.String = 'Tiempo';
            app.axes3.YLabel.String = 'Densidad del fotón m^-3';
            
            plot(app.axes4,t,y2);
            app.axes4.Title.String = 'Espectro respuesta impulso con I=15mA';
            app.axes4.XLabel.String = 'Tiempo';
            app.axes4.YLabel.String = 'Densidad del fotón m^-3';
        end

        % Button pushed function: graf_freq
        function graf_freqButtonPushed(app, event)
            frecuencia;
            figure('Units',"normalized",'Position',[0,0,1,1]);
            subplot(2,2,1);
            bode(sys);
            title('Gráfica de bode para I=10mA');
            subplot(2,2,2);
            bode(sys_15);
            title('Gráfica de bode para I=15mA');
            subplot(2,2,3);
            nyquist(sys)
            title('Gráfica de polos y ceros para I=10mA');
            subplot(2,2,4);
            nyquist(sys_15)
            title('Gráfica de polos y ceros para I=15mA');
            
            app.Band_10m.Text=sprintf("%.2f GHz",bandwidth(sys)/1e9);
            app.Band_15m.Text=sprintf("%.2f GHz",bandwidth(sys_15)/1e9);
            
        end

        % Button pushed function: graf_pot
        function graf_potButtonPushed(app, event)
            potencia;
            plot(app.axes5,1000.*u,1000.*p)
            app.axes5.Title.String = 'Respuesta de la potencia ante la corriente de entrada';
            app.axes5.XLabel.String = 'Corriente [mA]';
            app.axes5.YLabel.String = 'Potencia [mW]';
            plot(app.axes6,1000.*u,1000000.*(p.^2))
            app.axes6.Title.String = 'Respuesta de la potencia ante la corriente de bombeo';
            app.axes6.XLabel.String = 'Corriente [mA]';
            app.axes6.YLabel.String = 'Potencia [mW]';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SaturacinTab = uitab(app.TabGroup);
            app.SaturacinTab.Title = 'Saturación';
            app.graf_sat = uibutton(app.SaturacinTab, 'push');
            app.graf_sat.ButtonPushedFcn = createCallbackFcn(app, @graf_satButtonPushed, true);
            app.graf_sat.Text = 'Graficar';
            app.axes1 = uiaxes(app.SaturacinTab);
            title(app.axes1, 'Title')
            xlabel(app.axes1, 'X')
            ylabel(app.axes1, 'Y')
            zlabel(app.axes1, 'Z')
            app.axes2 = uiaxes(app.SaturacinTab);
            title(app.axes2, 'Title')
            xlabel(app.axes2, 'X')
            ylabel(app.axes2, 'Y')
            zlabel(app.axes2, 'Z')
            app.EspectroTab = uitab(app.TabGroup);
            app.EspectroTab.Title = 'Espectro ';
            app.graf_espec = uibutton(app.EspectroTab, 'push');
            app.graf_espec.ButtonPushedFcn = createCallbackFcn(app, @graf_especButtonPushed, true);
            app.graf_espec.Text = 'Graficar';
            app.axes3 = uiaxes(app.EspectroTab);
            title(app.axes3, 'Title')
            xlabel(app.axes3, 'X')
            ylabel(app.axes3, 'Y')
            zlabel(app.axes3, 'Z')
            app.axes4 = uiaxes(app.EspectroTab);
            title(app.axes4, 'Title')
            xlabel(app.axes4, 'X')
            ylabel(app.axes4, 'Y')
            zlabel(app.axes4, 'Z')
            app.FrecuenciaTab = uitab(app.TabGroup);
            app.FrecuenciaTab.Title = 'Frecuencia';
            app.graf_freq = uibutton(app.FrecuenciaTab, 'push');
            app.graf_freq.ButtonPushedFcn = createCallbackFcn(app, @graf_freqButtonPushed, true);
            app.graf_freq.Text = 'Graficar';
            app.Anchodebandaa10mALabel = uilabel(app.FrecuenciaTab);
            app.Anchodebandaa10mALabel.Text = 'Ancho de banda a 10 mA';
            app.Band_10m = uilabel(app.FrecuenciaTab);
            app.Band_10m.Text = '0';
            app.Anchodebandaa15mALabel = uilabel(app.FrecuenciaTab);
            app.Anchodebandaa15mALabel.Text = 'Ancho de banda a 15 mA';
            app.Band_15m = uilabel(app.FrecuenciaTab);
            app.Band_15m.Text = '0';
            app.PotenciaTab = uitab(app.TabGroup);
            app.PotenciaTab.Title = 'Potencia';
            app.graf_pot = uibutton(app.PotenciaTab, 'push');
            app.graf_pot.ButtonPushedFcn = createCallbackFcn(app, @graf_potButtonPushed, true);
            app.graf_pot.Text = 'Graficar';
            app.axes5 = uiaxes(app.PotenciaTab);
            title(app.axes5, 'Title')
            xlabel(app.axes5, 'X')
            ylabel(app.axes5, 'Y')
            zlabel(app.axes5, 'Z')
            app.axes6 = uiaxes(app.PotenciaTab);
            title(app.axes6, 'Title')
            xlabel(app.axes6, 'X')
            ylabel(app.axes6, 'Y')
            zlabel(app.axes6, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_diodo_laser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end