classdef pobalcion_calculo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FactordecrecimientoSlider       matlab.ui.control.Slider
        FactordecrecimientoSliderLabel  matlab.ui.control.Label
        PobalcininicialSlider           matlab.ui.control.Slider
        PobalcininicialSliderLabel      matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: PobalcininicialSlider
        function PobalcininicialSliderValueChanging(app, event)
            x0 = event.Value;
            r= app.FactordecrecimientoSlider.Value;
            x=zeros(1,100);
            x(1)=x0;
            for t=2:length(x)
                x(t)=r*x(t-1)*(1-x(t-1));
            end
            plot(app.UIAxes,x);
            plot(app.UIAxes2,x(2:end),x(1:end-1));
        end

        % Value changing function: FactordecrecimientoSlider
        function FactordecrecimientoSliderValueChanging(app, event)
            x0=app.PobalcininicialSlider.Value;
            r = event.Value;
            x=zeros(1,100);
            x(1)=x0;
            for t=2:length(x)
                x(t)=r*x(t-1)*(1-x(t-1));
            end
            plot(app.UIAxes,x);
            plot(app.UIAxes2,x(2:end),x(1:end-1));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pobación')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Población pu')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Gráfica de crecimiento')
            xlabel(app.UIAxes2, 'Xn')
            ylabel(app.UIAxes2, 'Xn+1')
            zlabel(app.UIAxes2, 'Z')
            app.PobalcininicialSliderLabel = uilabel(app.UIFigure);
            app.PobalcininicialSliderLabel.Text = 'Pobalción inicial';
            app.PobalcininicialSlider = uislider(app.UIFigure);
            app.PobalcininicialSlider.Limits = [0 1];
            app.PobalcininicialSlider.ValueChangingFcn = createCallbackFcn(app, @PobalcininicialSliderValueChanging, true);
            app.FactordecrecimientoSliderLabel = uilabel(app.UIFigure);
            app.FactordecrecimientoSliderLabel.Text = 'Factor de crecimiento';
            app.FactordecrecimientoSlider = uislider(app.UIFigure);
            app.FactordecrecimientoSlider.Limits = [0 4];
            app.FactordecrecimientoSlider.ValueChangingFcn = createCallbackFcn(app, @FactordecrecimientoSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pobalcion_calculo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end