classdef vertedero < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DatosdesalidaPanel            matlab.ui.container.Panel
        AnchodelvertederoxmLabel      matlab.ui.control.Label
        x                             matlab.ui.control.Label
        TirantenormalymLabel          matlab.ui.control.Label
        y                             matlab.ui.control.Label
        AreahidrulicaAm2Label         matlab.ui.control.Label
        A                             matlab.ui.control.Label
        PermetroPmLabel               matlab.ui.control.Label
        P                             matlab.ui.control.Label
        RadiohidrulicoRmLabel         matlab.ui.control.Label
        VelocidadmsLabel              matlab.ui.control.Label
        R                             matlab.ui.control.Label
        Vel                           matlab.ui.control.Label
        DatosdeingresoPanel           matlab.ui.container.Panel
        CalculadoradeVertederosLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        CaudalQm3sLabel               matlab.ui.control.Label
        AnchodesoleramLabel           matlab.ui.control.Label
        Q                             matlab.ui.control.NumericEditField
        b                             matlab.ui.control.NumericEditField
        CalcularButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            app.x.Text=num2str(app.b.Value);
            yc = ((app.Q.Value)^2/((app.b.Value)^2*9.81))^(1/3);
            app.y.Text = num2str(yc);
            Ai = yc^2;
            Pi = 2*yc+app.b.Value;
            Ri= Ai/Pi;
            app.A.Text=num2str(Ai);
            app.P.Text=num2str(Pi);
            app.R.Text=num2str(Ri);
            Si = (0.013*app.Q.Value/(Ri^(2/3)*Ai))^2;
            app.Vel.Text=num2str(Si);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DatosdesalidaPanel = uipanel(app.UIFigure);
            app.DatosdesalidaPanel.Title = 'Datos de salida';
            app.AnchodelvertederoxmLabel = uilabel(app.DatosdesalidaPanel);
            app.AnchodelvertederoxmLabel.Text = 'Ancho del vertedero x (m)';
            app.x = uilabel(app.DatosdesalidaPanel);
            app.x.Text = '0';
            app.TirantenormalymLabel = uilabel(app.DatosdesalidaPanel);
            app.TirantenormalymLabel.Text = 'Tirante normal y (m)';
            app.y = uilabel(app.DatosdesalidaPanel);
            app.y.Text = '0';
            app.AreahidrulicaAm2Label = uilabel(app.DatosdesalidaPanel);
            app.AreahidrulicaAm2Label.Text = 'Area hidráulica A (m^2)';
            app.A = uilabel(app.DatosdesalidaPanel);
            app.A.Text = '0';
            app.PermetroPmLabel = uilabel(app.DatosdesalidaPanel);
            app.PermetroPmLabel.Text = 'Perímetro P (m)';
            app.P = uilabel(app.DatosdesalidaPanel);
            app.P.Text = '0';
            app.RadiohidrulicoRmLabel = uilabel(app.DatosdesalidaPanel);
            app.RadiohidrulicoRmLabel.Text = 'Radio hidráulico R (m)';
            app.VelocidadmsLabel = uilabel(app.DatosdesalidaPanel);
            app.VelocidadmsLabel.Text = 'Velocidad (m/s)';
            app.R = uilabel(app.DatosdesalidaPanel);
            app.R.Text = '0';
            app.Vel = uilabel(app.DatosdesalidaPanel);
            app.Vel.Text = '0';
            app.DatosdeingresoPanel = uipanel(app.UIFigure);
            app.DatosdeingresoPanel.Title = 'Datos de ingreso';
            app.CalculadoradeVertederosLabel = uilabel(app.UIFigure);
            app.CalculadoradeVertederosLabel.Text = 'Calculadora de Vertederos';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'vertedero.jpg';
            app.CaudalQm3sLabel = uilabel(app.UIFigure);
            app.CaudalQm3sLabel.Text = 'Caudal Q (m^3/s)';
            app.AnchodesoleramLabel = uilabel(app.UIFigure);
            app.AnchodesoleramLabel.Text = 'Ancho de solera (m)';
            app.Q = uieditfield(app.UIFigure, 'numeric');
            app.b = uieditfield(app.UIFigure, 'numeric');
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vertedero

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end